classdef final_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EntertheValuesLabel            matlab.ui.control.Label
        MODULATIONSCHEMESUSINGMATLABGUILabel  matlab.ui.control.Label
        ModulationindexEditField       matlab.ui.control.NumericEditField
        ModulationindexEditFieldLabel  matlab.ui.control.Label
        VSBButton                      matlab.ui.control.Button
        SSBSCButton                    matlab.ui.control.Button
        FMButton                       matlab.ui.control.Button
        DSBSCButton                    matlab.ui.control.Button
        AMButton                       matlab.ui.control.Button
        VoltageofCarrierSignalEditField  matlab.ui.control.NumericEditField
        VoltageofCarrierSignalEditFieldLabel  matlab.ui.control.Label
        FrequencyofCarrierSignalEditField  matlab.ui.control.NumericEditField
        FrequencyofCarrierSignalEditFieldLabel  matlab.ui.control.Label
        FrequencyofMessageSignalEditField  matlab.ui.control.NumericEditField
        FrequencyofMessageSignalEditFieldLabel  matlab.ui.control.Label
        VoltageofMessageSignalEditField  matlab.ui.control.NumericEditField
        VoltageofMessageSignalEditFieldLabel  matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FmEditFieldValueChanged(app, event)
           % fm = app. FrequencyofMessageSignalEditField.Value;
            %vm = app.VoltageofMessageSignalEditField.Value;
            %Ta = 1/fm;
            %t = 0: Ta/999:6*Ta;
            %ym = vm*sin(2*pi*fm*t);
            %plot(app.UIAxes, ym, 'r')
        end

        % Callback function
        function FcEditFieldValueChanged(app, event)
            %fm = app.FrequencyofMessageSignalEditField.Value;
            %fc = app.FrequencyofCarrierSignalEditField.Value;
            %vc = app.VoltageofCarrierSignalEditField.Value;
            %Ta = 1/fm;
            %t=0:Ta/999:6*Ta;
            %yc=vc*sin(2*pi*fc*t); % Equation of carrier signal 
            %plot(app.UIAxes2, yc)
        end

        % Callback function
        function ModulationSliderValueChanged(app, event)
            %m=app.ModulationSlider.Value;
            %m = 0.01*m;
            %vc = app.VoltageofCarrierSignalEditField.Value;
            %fm = app.FrequencyofMessageSignalEditField.Value;
            %fc = app.FrequencyofCarrierSignalEditField.Value;
            %Ta=1/fm;
            %t=0: Ta/999:6*Ta;
            %y = vc*(1+m*sin(2*pi*fm*t)).*sin(2*pi*fc*t);
            %plot(app.UIAxes3, y, 'g')
        end

        % Button pushed function: AMButton
        function AMButtonPushed(app, event)
            fc = app.FrequencyofCarrierSignalEditField.Value;
            vm = app.VoltageofMessageSignalEditField.Value;
            fm = app.FrequencyofMessageSignalEditField.Value;
            vc = app.VoltageofCarrierSignalEditField.Value;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate modulation index for AM
            m = vm / vc;

            % Update Modulation Index Edit Field
            app.ModulationindexEditField.Value = m;

            % Calculate AM modulation signal
            m = vm / vc; % Modulation index
            message_signal = vm*sin(2*pi*fm*t);
            carrier_signal = vc * sin(2*pi*fc*t);
            modulated_signal = vc * (1 + m * sin(2*pi*fm*t)) .* sin(2*pi*fc*t);
            
            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'AM Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'AM In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: FMButton
        function FMButtonPushed(app, event)
            % Assuming you have the following values from your UI or elsewhere
            fc = app.FrequencyofCarrierSignalEditField.Value; % Carrier frequency
            vc = app.VoltageofCarrierSignalEditField.Value; % Carrier amplitude
            fm = app.FrequencyofMessageSignalEditField.Value; % Modulating frequency
            vm = app.VoltageofMessageSignalEditField.Value; % Modulating amplitude

             % Assume a frequency deviation (Δf), you can choose a value for Δf or fetch it from the user
            delta_f = 75; % Frequency deviation (example value)

            % Calculate modulation index for FM
            beta = delta_f / fm;

            % Update Modulation Index Edit Field
            app.ModulationindexEditField.Value = beta;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate FM modulation signal

            % Carrier signal
            carrier_signal = vc * sin(2*pi*fc*t);

            % Modulated signal (FM modulation)
            modulated_signal = vc * sin(2*pi*fc*t + beta * sin(2*pi*fm*t));

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, vm * sin(2*pi*fm*t));
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'FM Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'FM In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: DSBSCButton
        function DSBSCButtonPushed(app, event)
            fc = app.FrequencyofCarrierSignalEditField.Value;
            vm = app.VoltageofMessageSignalEditField.Value;
            fm = app.FrequencyofMessageSignalEditField.Value;
            vc = app.VoltageofCarrierSignalEditField.Value;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate modulation index for AM
            m = vm / vc;

            % Update Modulation Index Edit Field
            app.ModulationindexEditField.Value = m;
            

            % Calculate DSB-SC modulation signal
            message_signal = vm * sin(2*pi*fm*t);
            carrier_signal = vc * sin(2*pi*fc*t);
            modulated_signal = message_signal.*carrier_signal;

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'DSB-SC Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'DSB-SC In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: SSBSCButton
        function SSBSCButtonPushed(app, event)
            fc = app.FrequencyofCarrierSignalEditField.Value;
            vm = app.VoltageofMessageSignalEditField.Value;
            fm = app.FrequencyofMessageSignalEditField.Value;
            vc = app.VoltageofCarrierSignalEditField.Value;
            v=(vm*vc)/2;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate modulation index for AM
            m = vm / vc;

            % Update Modulation Index Edit Field
            app.ModulationindexEditField.Value = m;
            

            % Calculate SSB modulation signal
            message_signal = vm * cos(2*pi*fm*t);
            carrier_signal = vc * cos(2*pi*fc*t);
            modulated_signal =v.*((cos((2*pi)*fm*t)).*(cos(2*pi*fc*t))-(sin(2*pi*fm*t).*sin(2*pi*fc*t)));

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'SSB Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'SSB-SC In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: VSBButton
        function VSBButtonPushed(app, event)
            % Parameters
            Fs = 1000;  % Sampling frequency (Hz)
            Fc = app.FrequencyofCarrierSignalEditField.Value;   % Carrier frequency (Hz), fetched from GUI
            fm = app.FrequencyofMessageSignalEditField.Value;   % Modulation frequency (Hz), fetched from GUI
            vm = app.VoltageofMessageSignalEditField.Value;
            vc = app.VoltageofCarrierSignalEditField.Value;

            % Time vector
            t = 0:1/Fs:1;  % Adjust time vector as needed

            % Calculate modulation index for AM
            m = vm / vc;

            % Update Modulation Index Edit Field
            app.ModulationindexEditField.Value = m;
            

            % Generate message signal (baseband)
            message_signal = cos(2*pi*10*t) + cos(2*pi*20*t);

            % Generate carrier signal
            carrier_signal = cos(2*pi*Fc*t);

            % SSB Modulation
            S = message_signal .* carrier_signal;  % Amplitude modulation (AM)
            S_vsb = ssbmod(message_signal, Fc, Fs, 0);  % Single Sideband modulation

            % Adding Vestigial Sideband (VSB)
            vestigial = 0.5 * S;  % Example of adding a vestigial component
            S_vsb_vestigial = S_vsb + vestigial;

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Message Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Carrier Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, S_vsb_vestigial);
            title(app.UIAxes3, 'VSB Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(S_vsb_vestigial));
            title(app.UIAxes4, 'VSB Modulated Signal');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Modulated Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Spectral Domain')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.VoltageofMessageSignalEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageofMessageSignalEditFieldLabel.Text = 'Voltage of Message Signal';
            app.VoltageofMessageSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyofMessageSignalEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyofMessageSignalEditFieldLabel.Text = 'Frequency of Message Signal';
            app.FrequencyofMessageSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyofCarrierSignalEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyofCarrierSignalEditFieldLabel.Text = 'Frequency of Carrier Signal';
            app.FrequencyofCarrierSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageofCarrierSignalEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageofCarrierSignalEditFieldLabel.Text = 'Voltage of Carrier Signal';
            app.VoltageofCarrierSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMButton = uibutton(app.UIFigure, 'push');
            app.AMButton.ButtonPushedFcn = createCallbackFcn(app, @AMButtonPushed, true);
            app.AMButton.Text = 'AM';
            app.DSBSCButton = uibutton(app.UIFigure, 'push');
            app.DSBSCButton.ButtonPushedFcn = createCallbackFcn(app, @DSBSCButtonPushed, true);
            app.DSBSCButton.Text = 'DSB-SC';
            app.FMButton = uibutton(app.UIFigure, 'push');
            app.FMButton.ButtonPushedFcn = createCallbackFcn(app, @FMButtonPushed, true);
            app.FMButton.Text = 'FM';
            app.SSBSCButton = uibutton(app.UIFigure, 'push');
            app.SSBSCButton.ButtonPushedFcn = createCallbackFcn(app, @SSBSCButtonPushed, true);
            app.SSBSCButton.Text = 'SSB-SC';
            app.VSBButton = uibutton(app.UIFigure, 'push');
            app.VSBButton.ButtonPushedFcn = createCallbackFcn(app, @VSBButtonPushed, true);
            app.VSBButton.Text = 'VSB';
            app.ModulationindexEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationindexEditFieldLabel.Text = 'Modulation index';
            app.ModulationindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.MODULATIONSCHEMESUSINGMATLABGUILabel = uilabel(app.UIFigure);
            app.MODULATIONSCHEMESUSINGMATLABGUILabel.Text = 'MODULATION SCHEMES USING MATLAB GUI';
            app.EntertheValuesLabel = uilabel(app.UIFigure);
            app.EntertheValuesLabel.Text = 'Enter the Values:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end